classdef KidneyStoneDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KidneyStoneDetectionAPPLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        DetectedRegionsLabel          matlab.ui.control.Label
        DetectionResultLabel          matlab.ui.control.Label
        ImageNameLabel                matlab.ui.control.Label
        SelectImageButton             matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes1                       matlab.ui.control.UIAxes
    end

    
     % Utility functions (private)
    methods (Access = private)

       function [processedImage, numRegions, metrics,stats] = processKidneyImage(app, originalImage)
    % Convert to grayscale
    if size(originalImage, 3) == 3 % this checks whether its RGB or not
        grayscaleImage = rgb2gray(originalImage);
    else
        grayscaleImage = originalImage;
    end

        %Apply median filtering to reduce noise
    filteredImage = medfilt2(grayscaleImage, [5 5]);

    % Thresholding using adaptive thresholding for robustness
    binaryImage = imbinarize(filteredImage, 'adaptive', 'ForegroundPolarity', 'bright', 'Sensitivity', 0.6);

     % Perform morphological operations to fill holes and remove small artifacts
     se = strel('disk', 5); % Increase structuring element size
     morphedImage = imclose(binaryImage, se);
     morphedImage = imfill(morphedImage, 'holes');
     morphedImage = bwareaopen(morphedImage, 1500); % Increased area threshold

     % Apply mask to original image
     processedImage = uint8(double(originalImage) .* repmat(morphedImage, [1 1 3]));
     
     % Adjust image contrast
   adjustedImage = imadjust(processedImage, [0.4 0.8], []) + 50;
 
% Convert to grayscale again
grayProcessedImage = rgb2gray(adjustedImage);
   
% Thresholding for stone detection with refined intensity
stoneRegions = grayProcessedImage > 220; % Adjusted intensity threshold
  
% Define ROI
[imageRows, imageCols] = size(stoneRegions);
xCenter = imageRows / 2;
yCenter = imageCols / 3;
rowPoints = [xCenter xCenter+200 xCenter+200 xCenter];
colPoints = [yCenter yCenter yCenter+40 yCenter+40];
roiMask = roipoly(stoneRegions, rowPoints, colPoints);

% Apply ROI mask
maskedImage = stoneRegions .* double(roiMask);

% Remove small objects with increased threshold
finalSegmentedImage = bwareaopen(maskedImage, 10);

% Count connected components
[labeledImage, numRegions] = bwlabel(finalSegmentedImage);


    % Compute region properties
    stats = regionprops(labeledImage, grayscaleImage, 'BoundingBox','Area', 'Centroid', 'MeanIntensity');

    % Convert stats to table format for displaying metrics
    if ~isempty(stats)
        metrics = struct2table(stats, 'AsArray', true);
    else
        metrics = table([], [], [], 'VariableNames', {'Area', 'Centroid', 'MeanIntensity'});
    end
    
    % Create processed image for display
    processedImage = uint8(double(originalImage) .* repmat(finalSegmentedImage, [1 1 size(originalImage, 3)]));
end
      
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            % Prompt user to select an image
            [filename, pathname] = uigetfile({'*.*', 'All Files (*.*)'; '*.jpg', 'JPEG Files (*.jpg)'; '*.png', 'PNG Files (*.png)'}, 'Select a Kidney Image');
            if isequal(filename, 0)
                 app.DetectionResultLabel.Text = 'No file selected'; %u can leave out this one
                return; % Exit if no file is selected
            end
            imagePath = fullfile(pathname, filename);

            % Update GUI with image name label
            app.ImageNameLabel.Text = ['Image Name: ', filename];

            % Load and display original image
            originalImage = imread(imagePath);
            imshow(originalImage, 'Parent', app.UIAxes1);
            title(app.UIAxes1, 'Original Image');

            % Process Image (Core Logic)
[processedImage, numRegions, metrics, stats] = app.processKidneyImage(originalImage);

% Update Detection Results
if numRegions > 0
    app.DetectionResultLabel.Text = 'Detection Result: Stone Detected';
    % Display processed image
    imshow(originalImage, 'Parent', app.UIAxes2);
    title(app.UIAxes2, 'Processed Image');
    hold(app.UIAxes2, 'on');
   
    for k = 1:length(stats)
        rectangle(app.UIAxes2, 'Position', stats(k).BoundingBox, 'EdgeColor', 'r', 'LineWidth', 2);
        end
    hold(app.UIAxes2, 'off');
else
    app.DetectionResultLabel.Text = 'Detection Result: No Stone Detected';
    % Display original image
    imshow(originalImage, 'Parent', app.UIAxes2); % Show original pic if no stones
    title(app.UIAxes2, 'Processed Image');
end

          %  app.DetectionResultLabel.Text = ['Detection Result: ', resultText];
            app.DetectedRegionsLabel.Text = ['Number of Detected Regions: ', num2str(numRegions)];

            % Populate Table with Metrics
            app.UITable.Data = metrics;
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Original Image')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Processed Image')
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImageNameLabel = uilabel(app.UIFigure);
            app.ImageNameLabel.Text = 'Image Name:';
            app.DetectionResultLabel = uilabel(app.UIFigure);
            app.DetectionResultLabel.Text = 'Detection Result:';
            app.DetectedRegionsLabel = uilabel(app.UIFigure);
            app.DetectedRegionsLabel.Text = 'Number of Detected Regions:';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Area'; 'Centroid'; 'Mean Intensity'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false];
            app.UITable.ColumnEditable = [true true false];
            app.KidneyStoneDetectionAPPLabel = uilabel(app.UIFigure);
            app.KidneyStoneDetectionAPPLabel.Text = 'Kidney Stone Detection APP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KidneyStoneDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end